classdef inductionmotormain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure  matlab.ui.Figure
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        EquivalentCircuitLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        Tab5                            matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        Tab6                            matlab.ui.container.Tab
        UIAxes6                         matlab.ui.control.UIAxes
        Tab7                            matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        EquivalentCircuitPanel          matlab.ui.container.Panel
        SmaxEditField                   matlab.ui.control.NumericEditField
        SmaxEditFieldLabel              matlab.ui.control.Label
        RcEditField                     matlab.ui.control.NumericEditField
        RcEditFieldLabel                matlab.ui.control.Label
        maximumtorqueEditField          matlab.ui.control.NumericEditField
        maximumtorqueEditFieldLabel     matlab.ui.control.Label
        startingtorqueEditField         matlab.ui.control.NumericEditField
        startingtorqueEditFieldLabel    matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        XmEditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2Label                         matlab.ui.control.Label
        R2sEditField                    matlab.ui.control.NumericEditField
        R2sEditFieldLabel               matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        R1EditField                     matlab.ui.control.NumericEditField
        R1EditFieldLabel                matlab.ui.control.Label
        DirectMethodPanel               matlab.ui.container.Panel
        slipEditField_2                 matlab.ui.control.NumericEditField
        slipLabel                       matlab.ui.control.Label
        calculateButton_2               matlab.ui.control.Button
        frequencyEditField_3            matlab.ui.control.NumericEditField
        frequencyEditField_3Label       matlab.ui.control.Label
        linevoltageEditField_3          matlab.ui.control.NumericEditField
        linevoltageEditField_3Label     matlab.ui.control.Label
        numberofpolesEditField          matlab.ui.control.NumericEditField
        numberofpolesEditFieldLabel     matlab.ui.control.Label
        magnetizationreactanceEditField  matlab.ui.control.NumericEditField
        magnetizationreactanceEditFieldLabel  matlab.ui.control.Label
        corelossresistanceEditField     matlab.ui.control.NumericEditField
        corelossresistanceEditFieldLabel  matlab.ui.control.Label
        rotorreactanceEditField         matlab.ui.control.NumericEditField
        rotorreactanceEditFieldLabel    matlab.ui.control.Label
        rotorresistanceEditField        matlab.ui.control.NumericEditField
        rotorresistanceEditFieldLabel   matlab.ui.control.Label
        statorreactanceEditField        matlab.ui.control.NumericEditField
        statorreactanceEditFieldLabel   matlab.ui.control.Label
        statorresistanceEditField       matlab.ui.control.NumericEditField
        statorresistanceEditFieldLabel  matlab.ui.control.Label
        TestingMethodPanel              matlab.ui.container.Panel
        calculate_2Button               matlab.ui.control.Button
        DCtestPanel                     matlab.ui.container.Panel
        rotorresistanceEditField_2      matlab.ui.control.NumericEditField
        rotorresistanceEditField_2Label  matlab.ui.control.Label
        slipEditField                   matlab.ui.control.NumericEditField
        slipEditFieldLabel              matlab.ui.control.Label
        polesEditField                  matlab.ui.control.NumericEditField
        polesEditFieldLabel             matlab.ui.control.Label
        idcEditField                    matlab.ui.control.NumericEditField
        idcEditFieldLabel               matlab.ui.control.Label
        vdcEditField                    matlab.ui.control.NumericEditField
        vdcEditFieldLabel               matlab.ui.control.Label
        lockedrotortestPanel            matlab.ui.container.Panel
        LRfrequencyEditField            matlab.ui.control.NumericEditField
        LRfrequencyEditFieldLabel       matlab.ui.control.Label
        LRinputpowerEditField           matlab.ui.control.NumericEditField
        LRinputpowerLabel               matlab.ui.control.Label
        LRlinecurrentEditField          matlab.ui.control.NumericEditField
        LRlinecurrentLabel              matlab.ui.control.Label
        LRlinevoltageEditField          matlab.ui.control.NumericEditField
        LRlinevoltageEditFieldLabel     matlab.ui.control.Label
        noloadtestPanel                 matlab.ui.container.Panel
        NLfrequencyEditField            matlab.ui.control.NumericEditField
        NLfrequencyEditFieldLabel       matlab.ui.control.Label
        NLinputpowerEditField           matlab.ui.control.NumericEditField
        NLinputpowerEditFieldLabel      matlab.ui.control.Label
        NLlinecurrentEditField          matlab.ui.control.NumericEditField
        NLlinecurrentLabel              matlab.ui.control.Label
        NLlinevoltageEditField          matlab.ui.control.NumericEditField
        NLlinevoltageEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton_2
        function calculateButton_2Pushed(app, event)
            
            r1=app.statorresistanceEditField.Value;          
            x1=app.statorreactanceEditField.Value;
            r2=app.rotorresistanceEditField.Value;
            x2=app.rotorreactanceEditField.Value;
            rc=app.corelossresistanceEditField.Value;
            xm=app.magnetizationreactanceEditField.Value;
            p=app.numberofpolesEditField.Value;
            v_l=app.linevoltageEditField_3.Value;
            fse=app.frequencyEditField_3.Value;
            slip=app.slipEditField_2.Value;
            v_p=v_l/(sqrt(3));
            n_sync=(120*fse)/p;
            w_sync=(2*pi*n_sync)/60;

            app.EditField.Value   = r1;
            app.EditField_2.Value = x1;
            app.EditField_3.Value = rc;
            app.EditField_4.Value = xm;
            app.EditField_5.Value = x2;
            app.EditField_6.Value = r2/slip;
            app.EditField_7.Value = v_p;
            
            v_th = v_p * (xm / sqrt(r1^2 + (x1 + xm )^2));
            z_th = ((j*xm) * (r1 + j * x1)) / (r1 + j * (x1 + xm)) ;
            r_th = real(z_th);
            x_th = imag(z_th); 
            
            
            s = (0:1:50)/50;
            s(1)=0.001;
            nm= (1-s) * n_sync;
            
            for ii = 1:51
              z_eq(ii)= r1 + j * x1 + 1 / ( (1/rc) - j*(1/xm) + (1/ ( (r2/s(ii)) + j*x2)) ) ;
              r_eq(ii) = real(z_eq(ii));
              x_eq(ii) = imag(z_eq(ii)); 
              i_L(ii)= v_p/z_eq(ii);
              i_1(ii)= v_p/sqrt((r_eq(ii)) ^2 + (x_eq(ii)) ^2 );
              p_in(ii)=3 *v_p * i_1(ii) * cos(angle(i_L(ii)));
            
            end
            plot(app.UIAxes6,nm,p_in,'m','LineWidth',2);
            
            
            for ii = 1:51
                
            t_ind(ii) = (3 * v_th^ 2 * r2 / s (ii)) / (w_sync * ((r_th + r2 / s(ii))^2 + (x_th + x2 )^ 2)); 
            
            end
           
            plot(app.UIAxes,nm,t_ind,'r','LineWidth',2)
            
            
            for ii=  1:51
            i_2(ii)= v_th/(sqrt((r_th + r2 / s(ii))^2 + (x_th + x2 )^ 2));
            end
            for ii=  1:51
            p_conv(ii)=3*i_2(ii)^2*r2*((1-s(ii))/s(ii));
            end
            
            plot(app.UIAxes2,nm,p_conv,'blue','LineWidth',2)
            
            starting_torque = (3 * v_th^ 2 * r2 ) / (w_sync * ((r_th + r2 )^2 + (x_th + x2 )^ 2)) ;
            pullout_torque = (3 * v_th^ 2)/(2 * w_sync * (r_th + sqrt(r_th^2 + (x_th + x2)^2) ) );
            smax=r2/(sqrt(r_th^2 + (x_th + x2)^2));
            
            for ii=  1:51
            p_rcl(ii) = 3 * i_2(ii) ^ 2 * r2;
            end
           
            plot(app.UIAxes3,nm,p_rcl,'yellow','LineWidth',2)

            for ii=  1:51
            e1(ii)= (v_th * sqrt(x2 ^ 2 + (r2 / s (ii))^2))/((r_th + ( r2 / s(ii) ) ) ^ 2+( x_th + x2) ^ 2);
            end
            for ii=  1:51
            p_core(ii) = (3 * e1(ii) ^ 2)/ rc ;
            end
           
            plot(app.UIAxes4,nm,p_core,'g','LineWidth',2)
            
            for ii=  1:51
            p_scl(ii) = 3 * i_1(ii) ^ 2 * r1 ;
            end
            
            
            plot(app.UIAxes5,nm,p_scl,'black','LineWidth',2)
            
            for ii= 1:51
                
                eff(ii)= p_conv(ii) / p_in(ii);
                
            end
            
            plot(app.UIAxes7,nm, eff, 'c','LineWidth',2 )
           
            
            app.R1EditField.Value=r1;
            app.X1EditField.Value=x1;
            app.R2sEditField.Value=r2/slip;
            app.X2EditField.Value=x2;
            app.XmEditField.Value=xm;
            app.startingtorqueEditField.Value=starting_torque;
            app.maximumtorqueEditField.Value=pullout_torque;
            app.RcEditField.Value=rc;
            app.SmaxEditField.Value=smax;


        end

        % Button pushed function: calculate_2Button
        function calculate_2ButtonPushed(app, event)
            vdc=app.vdcEditField.Value;
            idc=app.idcEditField.Value;
            p=app.polesEditField.Value;
            NLv_t=app.NLlinevoltageEditField.Value;
            NLi_l=app.NLlinecurrentEditField.Value;
            NLf=app.NLfrequencyEditField.Value;
            NLp_in=app.NLinputpowerEditField.Value;
            LRv_t=app.LRlinevoltageEditField.Value;
            LRi_l=app.LRlinecurrentEditField.Value;
            LRf=app.LRfrequencyEditField.Value;
            LRp_in=app.LRinputpowerEditField.Value;
            slip=app.slipEditField.Value;
            rc=app.rotorresistanceEditField_2.Value;


            NLv_p= NLv_t/sqrt(3);
            LRv_p= LRv_t /sqrt(3);
            n_sync=(120*NLf)/p;
            w_sync=(2*pi*n_sync)/60;
            
            
            znl=NLv_p/NLi_l;
            zlr=LRv_p/LRi_l;
            
            r1=vdc/(2*idc);
            theta=acosd(LRp_in/(3*LRv_p*LRi_l));
            rlr=zlr*cosd(theta);
            r2=rlr-r1;
            xlr_test=zlr*sind(theta);
            xlr_rated= (NLf*xlr_test) / LRf;
            x1=xlr_rated/2;
            x2=xlr_rated/2;
            
            xm=znl-x1;
            
            app.EditField.Value   = r1;
            app.EditField_2.Value = x1;
            app.EditField_3.Value = rc;
            app.EditField_4.Value = xm;
            app.EditField_5.Value = x2;
            app.EditField_6.Value = r2/slip;
            app.EditField_7.Value = NLv_p;    % Vp shown on diagram


            v_th = NLv_p * (xm / sqrt(r1^2 + (x1 + xm )^2));
            z_th = ((j*xm) * (r1 + j * x1)) / (r1 + j * (x1 + xm)) ;
            r_th = real(z_th);
            x_th = imag(z_th); 
            
            s = (0:1:50)/50;
            s(1)=0.001;
            nm= (1-s) * n_sync;
            
            for ii = 1:51
              z_eq(ii)= r1 + j * x1 + 1 / ( - j*(1/xm) + (1/ ( (r2/s(ii)) + j*x2)) ) ;
              r_eq(ii) = real(z_eq(ii));
              x_eq(ii) = imag(z_eq(ii)); 
              i_L(ii)= NLv_p/z_eq(ii);
            
            end
            
            for ii = 1:51
            i_1(ii)= NLv_p/sqrt((r_eq(ii)) ^2 + (x_eq(ii)) ^2 );
            p_in(ii)= 3 * NLv_p * i_1(ii)* cos(angle(i_L(ii)));
            
            end
            
            plot(app.UIAxes6,nm,p_in,'m','LineWidth',2)
            
            for ii = 1:51
                
            t_ind(ii) = (3 * v_th^ 2 * r2 / s (ii)) / (w_sync * ((r_th + r2 / s(ii))^2 + (x_th + x2 )^ 2)); 
            
            end
            
            plot(app.UIAxes,nm,t_ind,'r','LineWidth',2)
            
            
            for ii=  1:51
            i_2(ii)= v_th/(sqrt((r_th + r2 / s(ii))^2 + (x_th + x2 )^ 2));
            end
            for ii=  1:51
            p_conv(ii)=3*i_2(ii)^2*r2*((1-s(ii))/s(ii));
            end
           
            plot(app.UIAxes2,nm,p_conv,'blue','LineWidth',2)
           
            starting_torque = (3 * v_th^ 2 * r2 ) / (w_sync * ((r_th + r2 )^2 + (x_th + x2 )^ 2)); 
            pullout_torque = (3 * v_th^ 2)/(2 * w_sync * (r_th + sqrt(r_th^2 + (x_th + x2)^2) ) );
            smax= r2 / (sqrt( r_th^2  +  (x_th + x2)^2 ) );
            
            for ii=  1:51
            p_rcl(ii) = 3 * i_2(ii) ^ 2 * r2;
            end
            
            plot(app.UIAxes3,nm,p_rcl,'yellow','LineWidth',2)
            
            for ii=  1:51
            e1(ii)= (v_th * sqrt(x2 ^ 2 + (r2 / s (ii))^2))/((r_th + ( r2 / s(ii) ) ) ^ 2+( x_th + x2) ^ 2);
            end
            for ii=  1:51
            p_core(ii) = (3 * e1(ii) ^ 2)/ rc ;
            end
          
            plot(app.UIAxes4,nm,p_core,'g','LineWidth',2)
            
            for ii=  1:51
            p_scl(ii) = 3 * i_1(ii) ^ 2 * r1 ;
            end
            
            plot(app.UIAxes5,nm,p_scl,'black','LineWidth',2)
            
            for ii=  1:51
                p_rot(ii)= p_in(ii) - p_scl(ii);
                eff(ii) = p_conv(ii)/p_in(ii);
                
            end
            
            plot(app.UIAxes7,nm,eff,'c','LineWidth',2)
            
%             x=0.001:NLv_t
%             plot(app.UIAxes8,x,0)
%             z_eq1= r1 + j * x1 + 1 / ( - j*(1/xm) + (1/ ( (r2/slip) + j*x2)) ) ;
%              i_L1= NLv_p/z_eq1;
%              m=tan(angle(i_L1))
%             plot(app.UIAxes8,)


            app.R1EditField.Value=r1;
            app.X1EditField.Value=x1;
            app.R2sEditField.Value=r2/slip;
            app.X2EditField.Value=x2;
            app.XmEditField.Value=xm;
            app.startingtorqueEditField.Value=starting_torque;
            app.maximumtorqueEditField.Value=pullout_torque;
            app.RcEditField.Value=rc;
            app.SmaxEditField.Value=smax;
            
            
        end

        % Callback function: not associated with a component
        function EditFieldValueChanged(app, event)
            
        end

        % Callback function: not associated with a component
        function EditField_2ValueChanged(app, event)
           
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            rc = app.EditField_3.Value;
            app.RcEditField.Value=rc;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            xm = app.EditField_4.Value;
            app.XmEditField.Value=xm;
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            x2 = app.EditField_5.Value;
            app.X2EditField.Value=x2;
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
              R2_over_s = app.EditField_6.Value;
              app.R2sEditField.Value = R2_over_s;
        end

        % Value changed function: startingtorqueEditField
        function startingtorqueEditFieldValueChanged(app, event)
           
        end

        % Value changed function: EditField
        function EditFieldValueChanged2(app, event)
             r1 = app.EditField.Value;
            app.R1EditField.Value=r1;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged2(app, event)
             x1 = app.EditField_2.Value;
            app.X1EditField.Value=x1;
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            v_p = app.EditField_7.Value;
            app.EditField_7.Value = v_p;
            NLv_p = app.EditField_7.Value;
            app.EditField_7.Value = NLv_p;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure = uifigure('Visible', 'off');
            app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure.Name = 'INDUCTION MOTOR CHARACTERISTICS AND PERFORMANCE ANALYSIS';
            app.TestingMethodPanel = uipanel(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.TestingMethodPanel.Title = 'Testing Method';
            app.noloadtestPanel = uipanel(app.TestingMethodPanel);
            app.noloadtestPanel.Title = 'no load test';
            app.NLlinevoltageEditFieldLabel = uilabel(app.noloadtestPanel);
            app.NLlinevoltageEditFieldLabel.Text = 'NL line voltage';
            app.NLlinevoltageEditField = uieditfield(app.noloadtestPanel, 'numeric');
            app.NLlinevoltageEditField.Value = 208;
            app.NLlinecurrentLabel = uilabel(app.noloadtestPanel);
            app.NLlinecurrentLabel.Text = 'NL line current ';
            app.NLlinecurrentEditField = uieditfield(app.noloadtestPanel, 'numeric');
            app.NLlinecurrentEditField.Value = 8.17;
            app.NLinputpowerEditFieldLabel = uilabel(app.noloadtestPanel);
            app.NLinputpowerEditFieldLabel.Text = 'NL input power';
            app.NLinputpowerEditField = uieditfield(app.noloadtestPanel, 'numeric');
            app.NLinputpowerEditField.Value = 420;
            app.NLfrequencyEditFieldLabel = uilabel(app.noloadtestPanel);
            app.NLfrequencyEditFieldLabel.Text = 'NLfrequency';
            app.NLfrequencyEditField = uieditfield(app.noloadtestPanel, 'numeric');
            app.NLfrequencyEditField.Value = 60;
            app.lockedrotortestPanel = uipanel(app.TestingMethodPanel);
            app.lockedrotortestPanel.Title = 'locked rotor test';
            app.LRlinevoltageEditFieldLabel = uilabel(app.lockedrotortestPanel);
            app.LRlinevoltageEditFieldLabel.Text = 'LR line voltage';
            app.LRlinevoltageEditField = uieditfield(app.lockedrotortestPanel, 'numeric');
            app.LRlinevoltageEditField.Value = 25;
            app.LRlinecurrentLabel = uilabel(app.lockedrotortestPanel);
            app.LRlinecurrentLabel.Text = 'LR line current  ';
            app.LRlinecurrentEditField = uieditfield(app.lockedrotortestPanel, 'numeric');
            app.LRlinecurrentEditField.Value = 27.9;
            app.LRinputpowerLabel = uilabel(app.lockedrotortestPanel);
            app.LRinputpowerLabel.Text = 'LR input power ';
            app.LRinputpowerEditField = uieditfield(app.lockedrotortestPanel, 'numeric');
            app.LRinputpowerEditField.Value = 920;
            app.LRfrequencyEditFieldLabel = uilabel(app.lockedrotortestPanel);
            app.LRfrequencyEditFieldLabel.Text = 'LRfrequency';
            app.LRfrequencyEditField = uieditfield(app.lockedrotortestPanel, 'numeric');
            app.LRfrequencyEditField.Value = 15;
            app.DCtestPanel = uipanel(app.TestingMethodPanel);
            app.DCtestPanel.Title = 'DC test';
            app.vdcEditFieldLabel = uilabel(app.DCtestPanel);
            app.vdcEditFieldLabel.Text = 'vdc';
            app.vdcEditField = uieditfield(app.DCtestPanel, 'numeric');
            app.vdcEditField.Value = 13.6;
            app.idcEditFieldLabel = uilabel(app.DCtestPanel);
            app.idcEditFieldLabel.Text = 'idc';
            app.idcEditField = uieditfield(app.DCtestPanel, 'numeric');
            app.idcEditField.Value = 28;
            app.polesEditFieldLabel = uilabel(app.DCtestPanel);
            app.polesEditFieldLabel.Text = 'poles';
            app.polesEditField = uieditfield(app.DCtestPanel, 'numeric');
            app.polesEditField.Value = 4;
            app.slipEditFieldLabel = uilabel(app.DCtestPanel);
            app.slipEditFieldLabel.Text = 'slip';
            app.slipEditField = uieditfield(app.DCtestPanel, 'numeric');
            app.slipEditField.Value = 0.111;
            app.rotorresistanceEditField_2Label = uilabel(app.DCtestPanel);
            app.rotorresistanceEditField_2Label.Text = 'rotor resistance';
            app.rotorresistanceEditField_2 = uieditfield(app.DCtestPanel, 'numeric');
            app.rotorresistanceEditField_2.Value = 2.2;
            app.calculate_2Button = uibutton(app.TestingMethodPanel, 'push');
            app.calculate_2Button.ButtonPushedFcn = createCallbackFcn(app, @calculate_2ButtonPushed, true);
            app.calculate_2Button.Text = 'calculate_2';
            app.DirectMethodPanel = uipanel(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.DirectMethodPanel.Title = 'Direct Method';
            app.statorresistanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.statorresistanceEditFieldLabel.Text = 'stator resistance';
            app.statorresistanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.statorresistanceEditField.Value = 0.641;
            app.statorreactanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.statorreactanceEditFieldLabel.Text = 'stator reactance';
            app.statorreactanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.statorreactanceEditField.Value = 1.106;
            app.rotorresistanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.rotorresistanceEditFieldLabel.Text = 'rotor resistance ';
            app.rotorresistanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.rotorresistanceEditField.Value = 0.332;
            app.rotorreactanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.rotorreactanceEditFieldLabel.Text = 'rotor reactance';
            app.rotorreactanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.rotorreactanceEditField.Value = 0.464;
            app.corelossresistanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.corelossresistanceEditFieldLabel.Text = 'core loss resistance';
            app.corelossresistanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.corelossresistanceEditField.Value = 0.01;
            app.magnetizationreactanceEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.magnetizationreactanceEditFieldLabel.Text = 'magnetization reactance';
            app.magnetizationreactanceEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.magnetizationreactanceEditField.Value = 26.3;
            app.numberofpolesEditFieldLabel = uilabel(app.DirectMethodPanel);
            app.numberofpolesEditFieldLabel.Text = 'number of poles';
            app.numberofpolesEditField = uieditfield(app.DirectMethodPanel, 'numeric');
            app.numberofpolesEditField.Value = 4;
            app.linevoltageEditField_3Label = uilabel(app.DirectMethodPanel);
            app.linevoltageEditField_3Label.Text = 'line voltage';
            app.linevoltageEditField_3 = uieditfield(app.DirectMethodPanel, 'numeric');
            app.linevoltageEditField_3.Value = 460;
            app.frequencyEditField_3Label = uilabel(app.DirectMethodPanel);
            app.frequencyEditField_3Label.Text = 'frequency';
            app.frequencyEditField_3 = uieditfield(app.DirectMethodPanel, 'numeric');
            app.frequencyEditField_3.Value = 60;
            app.calculateButton_2 = uibutton(app.DirectMethodPanel, 'push');
            app.calculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @calculateButton_2Pushed, true);
            app.calculateButton_2.Text = 'calculate';
            app.slipLabel = uilabel(app.DirectMethodPanel);
            app.slipLabel.Text = 'slip   ';
            app.slipEditField_2 = uieditfield(app.DirectMethodPanel, 'numeric');
            app.slipEditField_2.Value = 0.396;
            app.EquivalentCircuitPanel = uipanel(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.EquivalentCircuitPanel.Title = 'Equivalent Circuit';
            app.R1EditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.X1EditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.R2sEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.R2sEditFieldLabel.Text = 'R2/s';
            app.R2sEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.X2Label = uilabel(app.EquivalentCircuitPanel);
            app.X2Label.Text = ' X2';
            app.X2EditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.XmEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.startingtorqueEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.startingtorqueEditFieldLabel.Text = 'starting torque';
            app.startingtorqueEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.startingtorqueEditField.ValueChangedFcn = createCallbackFcn(app, @startingtorqueEditFieldValueChanged, true);
            app.maximumtorqueEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.maximumtorqueEditFieldLabel.Text = 'maximum torque';
            app.maximumtorqueEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.RcEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.RcEditFieldLabel.Text = 'Rc';
            app.RcEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.SmaxEditFieldLabel = uilabel(app.EquivalentCircuitPanel);
            app.SmaxEditFieldLabel.Text = 'Smax';
            app.SmaxEditField = uieditfield(app.EquivalentCircuitPanel, 'numeric');
            app.TabGroup = uitabgroup(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Induced Torque-Speed Curve')
            xlabel(app.UIAxes, 'nm (rpm)')
            ylabel(app.UIAxes, 't_ind (N.m)')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Converted Power - Speed Curve')
            xlabel(app.UIAxes2, 'nm(rpm)')
            ylabel(app.UIAxes2, 'p_Conv')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, 'Rotor Copper Loss - Speed Curve')
            xlabel(app.UIAxes3, 'nm (rpm)')
            ylabel(app.UIAxes3, 'p_Rcl (W)')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes4 = uiaxes(app.Tab4);
            title(app.UIAxes4, 'Core Loss - Speed Curve')
            xlabel(app.UIAxes4, 'nm (rpm)')
            ylabel(app.UIAxes4, 'p_core (W)')
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes5 = uiaxes(app.Tab5);
            title(app.UIAxes5, 'Stator Copper Loss - Speed Curve')
            xlabel(app.UIAxes5, 'nm (rpm)')
            ylabel(app.UIAxes5, 'p_Scl (W)')
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes6 = uiaxes(app.Tab6);
            title(app.UIAxes6, 'Input Power -Speed Curve')
            xlabel(app.UIAxes6, 'nm (rpm)')
            ylabel(app.UIAxes6, 'p_In')
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.UIAxes7 = uiaxes(app.Tab7);
            title(app.UIAxes7, 'Efficiency - Speed Curve')
            xlabel(app.UIAxes7, 'nm (rpm)')
            ylabel(app.UIAxes7, 'eff ')
            app.EquivalentCircuitLabel = uilabel(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.EquivalentCircuitLabel.Text = 'Equivalent Circuit';
            app.Image = uiimage(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Equivalent-circuit-diagram-of-induction-motor_W6402.png');
            app.EditField = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged2, true);
            app.EditField_2 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged2, true);
            app.EditField_3 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            % Show the figure after all components are created
            app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inductionmotormain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.INDUCTIONMOTORCHARACTERISTICSANDPERFORMANCEANALYSISUIFigure)
        end
    end
end